classdef ImagingPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        COMResetMenu              matlab.ui.container.Menu
        COMReinitMenu             matlab.ui.container.Menu
        Panel                     matlab.ui.container.Panel
        MoveUpButton              matlab.ui.control.Button
        MoveRightButton           matlab.ui.control.Button
        MoveLeftButton            matlab.ui.control.Button
        MoveDownButton            matlab.ui.control.Button
        DistancemmEditFieldLabel  matlab.ui.control.Label
        DistancemmEditField       matlab.ui.control.NumericEditField
        TabGroup                  matlab.ui.container.TabGroup
        ImageTab                  matlab.ui.container.Tab
        SaveImageButton           matlab.ui.control.Button
        SaveDirEditFieldLabel     matlab.ui.control.Label
        SaveDirEditField          matlab.ui.control.EditField
        BrowseButton              matlab.ui.control.Button
        FileNameEditFieldLabel    matlab.ui.control.Label
        FileNameEditField         matlab.ui.control.EditField
        ImageFormatDropDownLabel  matlab.ui.control.Label
        ImageFormatDropDown       matlab.ui.control.DropDown
        TextArea                  matlab.ui.control.TextArea
        MotorTab                  matlab.ui.container.Tab
        RecenterPlatformButton    matlab.ui.control.Button
        InvertTopBottomCheckBox   matlab.ui.control.CheckBox
        InvertLeftRightCheckBox   matlab.ui.control.CheckBox
        PreviewTab                matlab.ui.container.Tab
        OpenPreviewWindowButton   matlab.ui.control.Button
        TabGroup2                 matlab.ui.container.TabGroup
        WhiteLEDsTab              matlab.ui.container.Tab
        SetWhiteLEDsButton        matlab.ui.control.Button
        IntensityKnobLabel_2      matlab.ui.control.Label
        WhiteIntensityKnob        matlab.ui.control.Knob
        ValueEditFieldLabel       matlab.ui.control.Label
        WhiteValueEditField       matlab.ui.control.NumericEditField
        BlueLEDsTab               matlab.ui.container.Tab
        IntensityKnobLabel        matlab.ui.control.Label
        BlueIntensityKnob         matlab.ui.control.Knob
        TopBlueCheckBox           matlab.ui.control.CheckBox
        LeftBlueCheckBox          matlab.ui.control.CheckBox
        BottomBlueCheckBox        matlab.ui.control.CheckBox
        RightBlueCheckBox         matlab.ui.control.CheckBox
        SetBlueLEDsButton         matlab.ui.control.Button
        TopLeftBlueCheckBox       matlab.ui.control.CheckBox
        TopRightBlueCheckBox      matlab.ui.control.CheckBox
        BottomLeftBlueCheckBox    matlab.ui.control.CheckBox
        BottomRightBlueCheckBox   matlab.ui.control.CheckBox
        ValueEditFieldLabel_2     matlab.ui.control.Label
        BlueValueEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        objects % Container for serial ports
        Ymm2step = -200 % number of steps needed to move Y stage 1 mm
        Xmm2step = -40 % number of steps needed to move Y stage 1 mm
        htop
        SaveDir % Save Directory
        output % Responce of last sent command
    end
    
    methods (Access = private)
        
        function [] = calExtents(app)
            [forward, backward] = serialCom.findextens(app.objects.Xmotor);
            app.objects.Cal.LRextents = [forward, backward];
            serialCom.returnHome(app.objects.Xmotor, app.objects.Cal.LRextents(1),app.objects.Cal.LRextents(2));
            [top, bottom] = serialCom.findextens(app.objects.Ymotor);
            app.objects.Cal.TBextents = [top, bottom];
            serialCom.returnHome(app.objects.Ymotor, app.objects.Cal.TBextents(1),app.objects.Cal.TBextents(2));
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.objects = PlatformObjects;
            catch err
                pattern = regexptranslate('wildcard','no*Select');
                loc = regexp(err.identifier,pattern, 'once');
                if ~isempty(loc)
                    if ~isempty(regexp(err.identifier,'Port', 'once'))
                        msg = 'No Port Selected:';
                    else
                        msg = 'No Device Selected:';
                    end
                    selection = uiconfirm(app.UIFigure,{msg,'Would you like to close ImagingPlatform?'},'NothingSelected',...
                        'Options',{'Close','Try Again'},...
                        'DefaultOption',1,'CancelOption',1,...
                        'Icon','error');
                    if strcmpi(selection,'close')
                        delete(app)
                    else
                        app.startupFcn();
                    end
                    return
                else
                    rethrow(err);
                end
            end
            h = preview(app.objects.VidObj);
            app.htop=ancestor(h,{'figure'},'toplevel');
            movegui(app.UIFigure);
            %             calExtents(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            if isempty(app.SaveDirEditField.Value)
                userProfile = getenv('USERPROFILE');
                documents = sprintf('%s\\My Documents', userProfile);
                app.SaveDir = uigetdir(documents,'Select Where to Save Images');
            else
                app.SaveDir = uigetdir(app.SaveDir,'Select Where to Save Images');
            end
            if ~isempty(app.SaveDir)
                app.SaveDirEditField.Value = app.SaveDir;
                app.SaveImageButton.Enable = 'on';
            end
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            dist = abs(app.Ymm2step*app.DistancemmEditField.Value);
            if app.InvertTopBottomCheckBox.Value == 0
                serialCom.stepMove(app.objects.Ymotor ,-dist);
            else
                serialCom.stepMove(app.objects.Ymotor ,dist);
            end
            app.output = serialCom.writeToSerial(app.objects.Ymotor,'D');
        end

        % Button pushed function: MoveLeftButton
        function MoveLeftButtonPushed(app, event)
            dist = abs(app.Xmm2step*app.DistancemmEditField.Value);
            if app.InvertLeftRightCheckBox.Value == 0
                serialCom.stepMove(app.objects.Xmotor ,dist);
            else
                serialCom.stepMove(app.objects.Xmotor ,-dist);
            end
            app.output = serialCom.writeToSerial(app.objects.Xmotor,'D');
        end

        % Button pushed function: MoveRightButton
        function MoveRightButtonPushed(app, event)
            dist = abs(app.Xmm2step*app.DistancemmEditField.Value);
            if app.InvertLeftRightCheckBox.Value == 0
                serialCom.stepMove(app.objects.Xmotor ,-dist);
            else
                serialCom.stepMove(app.objects.Xmotor ,dist);
            end
            app.output = serialCom.writeToSerial(app.objects.Xmotor,'D');
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            dist = abs(app.Ymm2step*app.DistancemmEditField.Value);
            if app.InvertTopBottomCheckBox.Value == 0
                serialCom.stepMove(app.objects.Ymotor ,dist);
            else
                serialCom.stepMove(app.objects.Ymotor ,-dist);
            end
            app.output = serialCom.writeToSerial(app.objects.Ymotor,'D');
        end

        % Button pushed function: OpenPreviewWindowButton
        function OpenPreviewWindowButtonPushed(app, event)
            if ~ishandle(app.htop)
                h = preview(app.objects.VidObj);
                app.htop=ancestor(h,{'figure'},'toplevel');
            end
        end

        % Button pushed function: RecenterPlatformButton
        function RecenterPlatformButtonPushed(app, event)
            serialCom.returnHome(app.objects.Xmotor);
            serialCom.returnHome(app.objects.Ymotor);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            saveName = app.FileNameEditField.Value;
            saveExt = app.ImageFormatDropDown.Value;
            [startIndex,endIndex] = regexpi(saveName,'\[DATE\]');
            if ~isempty(startIndex)
                if (startIndex == 1 )&& (endIndex == length(saveName))
                    saveName = datestr(now,'yyyymmdd_HHMMSS');
                elseif (startIndex == 1 )
                    saveName = [datestr(now,'yyyymmdd_HHMMSS'),saveName(endIndex+1:end)];
                elseif (endIndex == length(saveName))
                    saveName = [saveName(1:startIndex-1),datestr(now,'yyyymmdd_HHMMSS')];
                else
                    saveName = [saveName(1:startIndex-1),datestr(now,'yyyymmdd_HHMMSS'),saveName(endIndex+1:end)];
                end
            end
            sufx = ' (1)';
            
            saveLoc = fullfile(app.SaveDir, [saveName, saveExt(2:end)]);
            saveLoc = nextname(saveLoc,sufx);
            file=java.io.File(saveLoc);
            if ~file.exists()
                try
                    imwrite(app.objects.CurImg, saveLoc);
                catch err
                    errordlg('An error occured. Please unplug and plug back in camera and restart program');
                end
            else
                i = 1;
                while file.exists()
                    saveLoc = fullfile(app.SaveDir, [saveName,'(',i,')', saveExt(2:end)]);
                    file=java.io.File(saveLoc);
                    i=i+1;
                end
                imwrite(app.objects.CurImg, saveLoc);
            end
            
        end

        % Button pushed function: SetBlueLEDsButton
        function SetBlueLEDsButtonPushed(app, event)
            val3 = app.TopBlueCheckBox.Value; % val 3
            val4 = app.LeftBlueCheckBox.Value; % val 4
            val2 = app.BottomBlueCheckBox.Value; % val 2
            val1 = app.RightBlueCheckBox.Value;% val 1
            % note these may need to change
            val5 = app.TopLeftBlueCheckBox.Value; % val 5
            val6 = app.TopRightBlueCheckBox.Value; % val 6
            val7 = app.BottomLeftBlueCheckBox.Value; % val 7
            val8 = app.BottomRightBlueCheckBox.Value;% val 8
            
            decvec = bi2de([val1,val2,val3,val4,val5,val6,val7,val8]);
            val=ceil((255/100)*app.BlueIntensityKnob.Value);
            if val<10
                val=['00',num2str(val)];
            elseif val <100
                val=['0',num2str(val)];
            else
                val=[num2str(val)];
            end
            if decvec<10
                decvec=['00',num2str(decvec)];
            elseif decvec <100
                decvec=['0',num2str(decvec)];
            else
                decvec=[num2str(decvec)];
            end
            serialCom.writeToSerial(app.objects.Ymotor,'LB000:000');
            serialCom.writeToSerial(app.objects.Ymotor,['LB',decvec,':',val]);
        end

        % Button pushed function: SetWhiteLEDsButton
        function SetWhiteLEDsButtonPushed(app, event)
            val3 = app.TopBlueCheckBox.Value; % val 3
            val4 = app.LeftBlueCheckBox.Value; % val 4
            val2 = app.BottomBlueCheckBox.Value; % val 2
            val1 = app.RightBlueCheckBox.Value;% val 1
            % note these may need to change
            val5 = app.TopLeftBlueCheckBox.Value; % val 5
            val6 = app.TopRightBlueCheckBox.Value; % val 6
            val7 = app.BottomLeftBlueCheckBox.Value; % val 7
            val8 = app.BottomRightBlueCheckBox.Value;% val 8
            
            decvec = bi2de([val1,val2,val3,val4,val5,val6,val7,val8]);
            val=ceil((255/100)*app.WhiteIntensityKnob.Value);
            if val<10
                val=['00',num2str(val)];
            elseif val <100
                val=['0',num2str(val)];
            else
                val=[num2str(val)];
            end
            if decvec<10
                decvec=['00',num2str(decvec)];
            elseif decvec <100
                decvec=['0',num2str(decvec)];
            else
                decvec=[num2str(decvec)];
            end
            serialCom.writeToSerial(app.objects.Ymotor,'LA000:000');
            serialCom.writeToSerial(app.objects.Ymotor,['LA',decvec,':',val]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.htop)
                close(app.htop);
            end
            try
                serialCom.writeToSerial(app.objects.Ymotor,'LA000:000');
                serialCom.writeToSerial(app.objects.Ymotor,'LB000:000');
            catch
            end
            serialCom.endAllSerial();
            delete(app);
            %             serialCom.endAllSerial();
        end

        % Value changed function: WhiteIntensityKnob
        function WhiteIntensityKnobValueChanged(app, event)
            value = app.WhiteIntensityKnob.Value;
            app.WhiteValueEditField.Value = value;
        end

        % Value changed function: WhiteValueEditField
        function WhiteValueEditFieldValueChanged(app, event)
            value = app.WhiteValueEditField.Value;
            app.WhiteIntensityKnob.Value= value;
        end

        % Value changed function: BlueIntensityKnob
        function BlueIntensityKnobValueChanged(app, event)
            value = app.BlueIntensityKnob.Value;
            app.BlueValueEditField.Value = value;
        end

        % Value changed function: BlueValueEditField
        function BlueValueEditFieldValueChanged(app, event)
            value = app.BlueValueEditField.Value;
            app.BlueIntensityKnob.Value = value;
        end

        % Menu selected function: COMResetMenu
        function COMResetMenuSelected(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.COMResetMenu = uimenu(app.Menu);
            app.COMResetMenu.MenuSelectedFcn = createCallbackFcn(app, @COMResetMenuSelected, true);
            app.COMResetMenu.Text = 'COM Reset';
            app.COMReinitMenu = uimenu(app.Menu);
            app.COMReinitMenu.Text = 'COM Reinit';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.MoveUpButton = uibutton(app.Panel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveRightButton = uibutton(app.Panel, 'push');
            app.MoveRightButton.ButtonPushedFcn = createCallbackFcn(app, @MoveRightButtonPushed, true);
            app.MoveRightButton.Text = 'Move Right';
            app.MoveLeftButton = uibutton(app.Panel, 'push');
            app.MoveLeftButton.ButtonPushedFcn = createCallbackFcn(app, @MoveLeftButtonPushed, true);
            app.MoveLeftButton.Text = 'Move Left';
            app.MoveDownButton = uibutton(app.Panel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.DistancemmEditFieldLabel = uilabel(app.Panel);
            app.DistancemmEditFieldLabel.Text = 'Distance (mm)';
            app.DistancemmEditField = uieditfield(app.Panel, 'numeric');
            app.DistancemmEditField.Limits = [0 Inf];
            app.DistancemmEditField.Value = 1;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.SaveImageButton = uibutton(app.ImageTab, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.SaveDirEditFieldLabel = uilabel(app.ImageTab);
            app.SaveDirEditFieldLabel.Text = 'Save Dir';
            app.SaveDirEditField = uieditfield(app.ImageTab, 'text');
            app.SaveDirEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.ImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileNameEditFieldLabel = uilabel(app.ImageTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ImageTab, 'text');
            app.FileNameEditField.Value = 'Image1';
            app.ImageFormatDropDownLabel = uilabel(app.ImageTab);
            app.ImageFormatDropDownLabel.Text = 'Image Format';
            app.ImageFormatDropDown = uidropdown(app.ImageTab);
            app.ImageFormatDropDown.Items = {'*.bmp', '*.gif', '*.jpeg', '*.png', '*.ras', '*.tiff'};
            app.ImageFormatDropDown.Value = '*.tiff';
            app.TextArea = uitextarea(app.ImageTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Tip: Add ''[DATE]'' to file name to replace ''[DATE]'' with a time stamp with the format: yyyymmdd_HHMMSS'};
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.RecenterPlatformButton = uibutton(app.MotorTab, 'push');
            app.RecenterPlatformButton.ButtonPushedFcn = createCallbackFcn(app, @RecenterPlatformButtonPushed, true);
            app.RecenterPlatformButton.Text = 'Recenter Platform';
            app.InvertTopBottomCheckBox = uicheckbox(app.MotorTab);
            app.InvertTopBottomCheckBox.Text = 'Invert Top/Bottom';
            app.InvertLeftRightCheckBox = uicheckbox(app.MotorTab);
            app.InvertLeftRightCheckBox.Text = 'Invert Left/Right';
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.OpenPreviewWindowButton = uibutton(app.PreviewTab, 'push');
            app.OpenPreviewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPreviewWindowButtonPushed, true);
            app.OpenPreviewWindowButton.Text = 'Open Preview Window';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.WhiteLEDsTab = uitab(app.TabGroup2);
            app.WhiteLEDsTab.Title = 'White LEDs';
            app.SetWhiteLEDsButton = uibutton(app.WhiteLEDsTab, 'push');
            app.SetWhiteLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SetWhiteLEDsButtonPushed, true);
            app.SetWhiteLEDsButton.Text = 'Set White LEDs';
            app.IntensityKnobLabel_2 = uilabel(app.WhiteLEDsTab);
            app.IntensityKnobLabel_2.Text = 'Intensity';
            app.WhiteIntensityKnob = uiknob(app.WhiteLEDsTab, 'continuous');
            app.WhiteIntensityKnob.ValueChangedFcn = createCallbackFcn(app, @WhiteIntensityKnobValueChanged, true);
            app.WhiteIntensityKnob.Value = 100;
            app.ValueEditFieldLabel = uilabel(app.WhiteLEDsTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.WhiteValueEditField = uieditfield(app.WhiteLEDsTab, 'numeric');
            app.WhiteValueEditField.ValueChangedFcn = createCallbackFcn(app, @WhiteValueEditFieldValueChanged, true);
            app.BlueLEDsTab = uitab(app.TabGroup2);
            app.BlueLEDsTab.Title = 'Blue LEDs';
            app.IntensityKnobLabel = uilabel(app.BlueLEDsTab);
            app.IntensityKnobLabel.Text = 'Intensity';
            app.BlueIntensityKnob = uiknob(app.BlueLEDsTab, 'continuous');
            app.BlueIntensityKnob.ValueChangedFcn = createCallbackFcn(app, @BlueIntensityKnobValueChanged, true);
            app.BlueIntensityKnob.Value = 100;
            app.TopBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopBlueCheckBox.Text = 'Top Blue';
            app.LeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.LeftBlueCheckBox.Text = 'Left Blue';
            app.BottomBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BottomBlueCheckBox.Text = 'Bottom Blue';
            app.RightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.RightBlueCheckBox.Text = 'Right Blue';
            app.SetBlueLEDsButton = uibutton(app.BlueLEDsTab, 'push');
            app.SetBlueLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SetBlueLEDsButtonPushed, true);
            app.SetBlueLEDsButton.Text = 'Set Blue LEDs';
            app.TopLeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopLeftBlueCheckBox.Text = 'Top/Left Blue';
            app.TopRightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopRightBlueCheckBox.Text = 'Top/Right Blue';
            app.BottomLeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BottomLeftBlueCheckBox.Text = 'Bottom/Left Blue';
            app.BottomRightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BottomRightBlueCheckBox.Text = 'Bottom/Right Blue';
            app.ValueEditFieldLabel_2 = uilabel(app.BlueLEDsTab);
            app.ValueEditFieldLabel_2.Text = 'Value';
            app.BlueValueEditField = uieditfield(app.BlueLEDsTab, 'numeric');
            app.BlueValueEditField.ValueChangedFcn = createCallbackFcn(app, @BlueValueEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImagingPlatform

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end